classdef Camera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CameraUIFigure    matlab.ui.Figure
        TakeaphotoButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        WebCamh; % Handler to webcam used to acquire photo
        DataManager; % object of DataManager class (from main window)
        MainWindow; % Handler to the app's main window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, manager, mainwindowh, camera)
           app.MainWindow = mainwindowh;
           app.DataManager = manager;
           app.WebCamh = camera;
           app.DataManager.ShowCameraPreview(app.WebCamh,app.UIAxes)
            
         
        end

        % Button pushed function: TakeaphotoButton
        function TakeaphotoButtonPushed(app, event)
            % Takes photo
            try
             img = app.DataManager.CaptureImageFromWebCam(app.WebCamh);
             ClassifyAndShowResults(app.MainWindow,img);
             delete(app.WebCamh);
             delete(app);
            catch ME
                uialert(app.CameraUIFigure ,'Something went wrong','Error');
            end
        end

        % Close request function: CameraUIFigure
        function CameraUIFigureCloseRequest(app, event)
            delete(app.WebCamh);
            delete(app);       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CameraUIFigure = uifigure('Visible', 'off');
            app.CameraUIFigure.Name = 'Camera';
            app.CameraUIFigure.Icon = 'camera.png';
            app.CameraUIFigure.CloseRequestFcn = createCallbackFcn(app, @CameraUIFigureCloseRequest, true);
            app.TakeaphotoButton = uibutton(app.CameraUIFigure, 'push');
            app.TakeaphotoButton.ButtonPushedFcn = createCallbackFcn(app, @TakeaphotoButtonPushed, true);
            app.TakeaphotoButton.Text = 'Take a photo';
            app.UIAxes = uiaxes(app.CameraUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.CameraUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Camera(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CameraUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CameraUIFigure)
        end
    end
end