classdef MaskDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskDetectorUIFigure  matlab.ui.Figure
        LoadimageButton       matlab.ui.control.Button
        MakephotoButton       matlab.ui.control.Button
        SaveresultsButton     matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        HelpLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageDataTable; % Table with data of loaded image(ImageData objects)
        Classificator;  % object of Classificator class
        DataManager; % object of DataManager class
        CameraApp; % Handler to the camera window
        FigureHandler; % Handler to figure on which outcome is displayed
        MyWebCam; % Webcam used to acquire photo
 
    end
    
    methods (Access = public)
        
        function ClassifyAndShowResults(app, img)
        % Function that classifies loaded image and shows the outcome in gui  
             LoadData = ImageData();
             LoadData.img = img;        
             % Resize image to fit into neural network
             I = imresize(LoadData.img,[227 227]);
             [LoadData.category,LoadData.probability] = app.Classificator.ClassifyPhoto(I);
             
             % Showing the results
             app.FigureHandler = figure;
             imshow(LoadData.img);
             title(strcat("Outcome: ",LoadData.category, " Accuracy: ",string(LoadData.probability),"%"));
             
             % Add the result to the table
             app.ImageDataTable = [app.ImageDataTable, LoadData];
             clear LoadData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          try
           app.Classificator = Classificator('Net.mat');
          catch ME
              uialert(app.MaskDetectorUIFigure ,'Something went wrong','Error');   
          end
           app.DataManager = DataManager();
         
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            
               
                % Checking if photo was read or not
                try
                     [img,read] = app.DataManager.LoadImageFromFile();
                     if (read)
                        app.ClassifyAndShowResults(img);
                     end    
                catch ME              
                    uialert(app.MaskDetectorUIFigure ,'Invalid file extension or file not found','Error');               
                end
             
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
          try
            app.DataManager.SaveResultsToFile(app.ImageDataTable);
            uialert(app.MaskDetectorUIFigure ,'Data Saved','Message','Icon','success');   
          catch
              uialert(app.MaskDetectorUIFigure ,'Something went wrong','Error');   
          end
        end

        % Button pushed function: MakephotoButton
        function MakephotoButtonPushed(app, event)
            
            % Error control
            try
             app.MyWebCam = webcam;
             app.CameraApp = Camera(app.DataManager,app, app.MyWebCam);
            catch ME
                if(strcmp(ME.identifier,'MATLAB:webcam:supportPkgNotInstalled'))
                    
                  uialert(app.MaskDetectorUIFigure ,'Matlab Support Package for USB Webcams is not insalled','Error','Icon','warning');
                else
                  uialert(app.MaskDetectorUIFigure ,'Camera not found or something went wrong','Error');
                end
            end
        end

        % Close request function: MaskDetectorUIFigure
        function MaskDetectorUIFigureCloseRequest(app, event)
            delete(app.FigureHandler);
            delete(app);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if(app.HelpLabel.Visible)
            set(app.HelpLabel,'Visible','off');
            else
                 set(app.HelpLabel,'Visible','on');
            end
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskDetectorUIFigure = uifigure('Visible', 'off');
            app.MaskDetectorUIFigure.Name = 'MaskDetector';
            app.MaskDetectorUIFigure.Icon = 'coronavirus (1).png';
            app.MaskDetectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @MaskDetectorUIFigureCloseRequest, true);
            app.LoadimageButton = uibutton(app.MaskDetectorUIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.MakephotoButton = uibutton(app.MaskDetectorUIFigure, 'push');
            app.MakephotoButton.ButtonPushedFcn = createCallbackFcn(app, @MakephotoButtonPushed, true);
            app.MakephotoButton.Text = {'Make photo'; ''};
            app.SaveresultsButton = uibutton(app.MaskDetectorUIFigure, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.Image = uiimage(app.MaskDetectorUIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'info (1).png';
            app.HelpLabel = uilabel(app.MaskDetectorUIFigure);
            app.HelpLabel.Visible = 'off';
            app.HelpLabel.Text = {'Welcome to MaskDetector app!'; ''; 'This is an app that verify if person on the photo wears safety mask or not.'; 'You can upload a photo from your computer or make a photo directly on the app to use it.'; 'Enjoy!'};
            % Show the figure after all components are created
            app.MaskDetectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaskDetectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskDetectorUIFigure)
        end
    end
end